classdef merlet_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        ReadMeTab              matlab.ui.container.Tab
        StaticTab              matlab.ui.container.Tab
        XposSliderLabel        matlab.ui.control.Label
        XposSlider             matlab.ui.control.Slider
        YposSliderLabel        matlab.ui.control.Label
        YposSlider             matlab.ui.control.Slider
        ZposSliderLabel        matlab.ui.control.Label
        ZposSlider             matlab.ui.control.Slider
        RollSliderLabel        matlab.ui.control.Label
        RollSlider             matlab.ui.control.Slider
        PitchSliderLabel       matlab.ui.control.Label
        PitchSlider            matlab.ui.control.Slider
        YawSliderLabel         matlab.ui.control.Label
        YawSlider              matlab.ui.control.Slider
        t1SliderLabel          matlab.ui.control.Label
        t1Slider               matlab.ui.control.Slider
        t2SliderLabel          matlab.ui.control.Label
        t2Slider               matlab.ui.control.Slider
        XposEditField          matlab.ui.control.NumericEditField
        YposEditField          matlab.ui.control.NumericEditField
        ZposEditField          matlab.ui.control.NumericEditField
        RollEditField          matlab.ui.control.NumericEditField
        PitchEditField         matlab.ui.control.NumericEditField
        YawEditField           matlab.ui.control.NumericEditField
        t1EditField            matlab.ui.control.NumericEditField
        t2EditField            matlab.ui.control.NumericEditField
        DynamicTab             matlab.ui.container.Tab
        XEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        RollEditField_2Label   matlab.ui.control.Label
        RollEditField_2        matlab.ui.control.NumericEditField
        PitchEditField_2Label  matlab.ui.control.Label
        PitchEditField_2       matlab.ui.control.NumericEditField
        YawEditField_2Label    matlab.ui.control.Label
        YawEditField_2         matlab.ui.control.NumericEditField
        sinLabel               matlab.ui.control.Label
        sinLabel_2             matlab.ui.control.Label
        sinLabel_3             matlab.ui.control.Label
        sinLabel_4             matlab.ui.control.Label
        sinLabel_5             matlab.ui.control.Label
        sinLabel_6             matlab.ui.control.Label
        XEditField_2           matlab.ui.control.NumericEditField
        YEditField_2           matlab.ui.control.NumericEditField
        ZEditField_2           matlab.ui.control.NumericEditField
        tLabel                 matlab.ui.control.Label
        tLabel_2               matlab.ui.control.Label
        tLabel_3               matlab.ui.control.Label
        tLabel_4               matlab.ui.control.Label
        tLabel_5               matlab.ui.control.Label
        tLabel_6               matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        PrepareTestButton      matlab.ui.control.ToggleButton
        BeginTestButton        matlab.ui.control.ToggleButton
        CancelTestButton       matlab.ui.control.ToggleButton
        RollEditField_3        matlab.ui.control.NumericEditField
        PitchEditField_3       matlab.ui.control.NumericEditField
        YawEditField_3         matlab.ui.control.NumericEditField
        WaypointTab            matlab.ui.container.Tab
        CalibrateTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)
         hello = 7;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RollSlider
        function rollValueChanged(app, event)
            value = app.RollSlider.Value;
            app.RollEditField.Value = value;
            assignin('base', "test", value);
        end

        % Value changed function: PitchSlider
        function pitchValueChanged(app, event)
            value = app.PitchSlider.Value;
            app.PitchEditField.Value = value;
            
        end

        % Value changed function: YawSlider
        function yawValueChanged(app, event)
            value = app.YawSlider.Value;
            app.YawEditField.Value = value;   
            
        end

        % Value changed function: XposSlider
        function xValueChanged(app, event)
            value = app.XposSlider.Value;
            app.XposEditField.Value = value;
        
        end

        % Value changed function: YposSlider
        function yValueChanged(app, event)
            value = app.YposSlider.Value;
            app.YposEditField.Value = value;
           
        end

        % Value changed function: ZposSlider
        function zValueChanged(app, event)
            value = app.ZposSlider.Value;
            app.ZposEditField.Value = value;
           
        end

        % Value changed function: t1Slider
        function t1SliderValueChanged(app, event)
            value = app.t1Slider.Value;
            app.t1EditField.Value = value;
            
        end

        % Value changed function: t2Slider
        function t2SliderValueChanged(app, event)
            value = app.t2Slider.Value;
            app.t2EditField.Value = value;
            
        end

        % Value changed function: XposEditField
        function XposEditFieldValueChanged(app, event)
            value = app.XposEditField.Value;
            app.XposSlider.Value = value;
            
        end

        % Value changed function: YposEditField
        function YposEditFieldValueChanged(app, event)
            value = app.YposEditField.Value;
            app.YposSlider.Value = value;
            
        end

        % Value changed function: ZposEditField
        function ZposEditFieldValueChanged(app, event)
            value = app.ZposEditField.Value;
            app.ZposSlider.Value = value;
            
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            value = app.RollEditField.Value;
            app.RollSlider.Value = value;
            
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;
            app.PitchSlider.Value = value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'ReadMe';
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.XposSliderLabel = uilabel(app.StaticTab);
            app.XposSliderLabel.Text = 'Xpos';
            app.XposSlider = uislider(app.StaticTab);
            app.XposSlider.Limits = [0 20];
            app.XposSlider.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.YposSliderLabel = uilabel(app.StaticTab);
            app.YposSliderLabel.Text = 'Ypos';
            app.YposSlider = uislider(app.StaticTab);
            app.YposSlider.Limits = [0 20];
            app.YposSlider.ValueChangedFcn = createCallbackFcn(app, @yValueChanged, true);
            app.ZposSliderLabel = uilabel(app.StaticTab);
            app.ZposSliderLabel.Text = 'Zpos';
            app.ZposSlider = uislider(app.StaticTab);
            app.ZposSlider.Limits = [0 20];
            app.ZposSlider.ValueChangedFcn = createCallbackFcn(app, @zValueChanged, true);
            app.RollSliderLabel = uilabel(app.StaticTab);
            app.RollSliderLabel.Text = {'Roll'; ''};
            app.RollSlider = uislider(app.StaticTab);
            app.RollSlider.Limits = [0 20];
            app.RollSlider.ValueChangedFcn = createCallbackFcn(app, @rollValueChanged, true);
            app.PitchSliderLabel = uilabel(app.StaticTab);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.StaticTab);
            app.PitchSlider.Limits = [0 20];
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @pitchValueChanged, true);
            app.YawSliderLabel = uilabel(app.StaticTab);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.StaticTab);
            app.YawSlider.Limits = [0 20];
            app.YawSlider.ValueChangedFcn = createCallbackFcn(app, @yawValueChanged, true);
            app.t1SliderLabel = uilabel(app.StaticTab);
            app.t1SliderLabel.Text = 't1';
            app.t1Slider = uislider(app.StaticTab);
            app.t1Slider.Limits = [0 20];
            app.t1Slider.ValueChangedFcn = createCallbackFcn(app, @t1SliderValueChanged, true);
            app.t2SliderLabel = uilabel(app.StaticTab);
            app.t2SliderLabel.Text = 't2';
            app.t2Slider = uislider(app.StaticTab);
            app.t2Slider.Limits = [0 20];
            app.t2Slider.ValueChangedFcn = createCallbackFcn(app, @t2SliderValueChanged, true);
            app.XposEditField = uieditfield(app.StaticTab, 'numeric');
            app.XposEditField.ValueDisplayFormat = '%6.5g';
            app.XposEditField.ValueChangedFcn = createCallbackFcn(app, @XposEditFieldValueChanged, true);
            app.YposEditField = uieditfield(app.StaticTab, 'numeric');
            app.YposEditField.ValueDisplayFormat = '%6.5g';
            app.YposEditField.ValueChangedFcn = createCallbackFcn(app, @YposEditFieldValueChanged, true);
            app.ZposEditField = uieditfield(app.StaticTab, 'numeric');
            app.ZposEditField.ValueDisplayFormat = '%6.5g';
            app.ZposEditField.ValueChangedFcn = createCallbackFcn(app, @ZposEditFieldValueChanged, true);
            app.RollEditField = uieditfield(app.StaticTab, 'numeric');
            app.RollEditField.ValueDisplayFormat = '%6.5g';
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditField = uieditfield(app.StaticTab, 'numeric');
            app.PitchEditField.ValueDisplayFormat = '%6.5g';
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditField = uieditfield(app.StaticTab, 'numeric');
            app.YawEditField.ValueDisplayFormat = '%6.5g';
            app.t1EditField = uieditfield(app.StaticTab, 'numeric');
            app.t1EditField.ValueDisplayFormat = '%6.5g';
            app.t2EditField = uieditfield(app.StaticTab, 'numeric');
            app.t2EditField.ValueDisplayFormat = '%6.5g';
            app.DynamicTab = uitab(app.TabGroup);
            app.DynamicTab.Title = 'Dynamic';
            app.XEditFieldLabel = uilabel(app.DynamicTab);
            app.XEditFieldLabel.Text = 'X:';
            app.XEditField = uieditfield(app.DynamicTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.DynamicTab);
            app.YEditFieldLabel.Text = 'Y:';
            app.YEditField = uieditfield(app.DynamicTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.DynamicTab);
            app.ZEditFieldLabel.Text = 'Z:';
            app.ZEditField = uieditfield(app.DynamicTab, 'numeric');
            app.RollEditField_2Label = uilabel(app.DynamicTab);
            app.RollEditField_2Label.Text = 'Roll';
            app.RollEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.PitchEditField_2Label = uilabel(app.DynamicTab);
            app.PitchEditField_2Label.Text = 'Pitch';
            app.PitchEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.YawEditField_2Label = uilabel(app.DynamicTab);
            app.YawEditField_2Label.Text = 'Yaw';
            app.YawEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.sinLabel = uilabel(app.DynamicTab);
            app.sinLabel.Text = '* sin (';
            app.sinLabel_2 = uilabel(app.DynamicTab);
            app.sinLabel_2.Text = '* sin (';
            app.sinLabel_3 = uilabel(app.DynamicTab);
            app.sinLabel_3.Text = '* sin (';
            app.sinLabel_4 = uilabel(app.DynamicTab);
            app.sinLabel_4.Text = '* sin (';
            app.sinLabel_5 = uilabel(app.DynamicTab);
            app.sinLabel_5.Text = '* sin (';
            app.sinLabel_6 = uilabel(app.DynamicTab);
            app.sinLabel_6.Text = '* sin (';
            app.XEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.YEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.ZEditField_2 = uieditfield(app.DynamicTab, 'numeric');
            app.RollEditField_3 = uieditfield(app.DynamicTab, 'numeric');
            app.PitchEditField_3 = uieditfield(app.DynamicTab, 'numeric');
            app.YawEditField_3 = uieditfield(app.DynamicTab, 'numeric');
            app.tLabel = uilabel(app.DynamicTab);
            app.tLabel.Text = '*t)';
            app.tLabel_2 = uilabel(app.DynamicTab);
            app.tLabel_2.Text = '*t)';
            app.tLabel_3 = uilabel(app.DynamicTab);
            app.tLabel_3.Text = '*t)';
            app.tLabel_4 = uilabel(app.DynamicTab);
            app.tLabel_4.Text = '*t)';
            app.tLabel_5 = uilabel(app.DynamicTab);
            app.tLabel_5.Text = '*t)';
            app.tLabel_6 = uilabel(app.DynamicTab);
            app.tLabel_6.Text = '*t)';
            app.ButtonGroup = uibuttongroup(app.DynamicTab);
            app.PrepareTestButton = uitogglebutton(app.ButtonGroup);
            app.PrepareTestButton.Text = 'Prepare Test';
            app.PrepareTestButton.Value = true;
            app.BeginTestButton = uitogglebutton(app.ButtonGroup);
            app.BeginTestButton.Text = {'Begin Test'; ''};
            app.CancelTestButton = uitogglebutton(app.ButtonGroup);
            app.CancelTestButton.Text = 'Cancel Test';
            app.WaypointTab = uitab(app.TabGroup);
            app.WaypointTab.Title = 'Waypoint';
            app.CalibrateTab = uitab(app.TabGroup);
            app.CalibrateTab.Title = 'Calibrate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = merlet_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end